classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StartButton                matlab.ui.control.StateButton
        PauseButton                matlab.ui.control.StateButton
        ResetButton                matlab.ui.control.StateButton
        OpenGripperButton          matlab.ui.control.StateButton
        CloseGripperButton         matlab.ui.control.StateButton
        EmergencyStopButton        matlab.ui.control.StateButton
        JointSlidersPanel          matlab.ui.container.Panel
        Joint1SliderLabel          matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        Joint4SliderLabel          matlab.ui.control.Label
        Joint4Slider               matlab.ui.control.Slider
        Joint5SliderLabel          matlab.ui.control.Label
        Joint5Slider               matlab.ui.control.Slider
        Joint6SliderLabel          matlab.ui.control.Label
        Joint6Slider               matlab.ui.control.Slider
        Joint7SliderLabel          matlab.ui.control.Label
        Joint7Slider               matlab.ui.control.Slider
        JointValuesPanel           matlab.ui.container.Panel
        Joint1Box                  matlab.ui.control.NumericEditField
        Joint2Box                  matlab.ui.control.NumericEditField
        Joint3Box                  matlab.ui.control.NumericEditField
        Joint4Box                  matlab.ui.control.NumericEditField
        Joint5Box                  matlab.ui.control.NumericEditField
        Joint6Box                  matlab.ui.control.NumericEditField
        Joint7Box                  matlab.ui.control.NumericEditField
        EndEffectorpositionsPanel  matlab.ui.container.Panel
        YSpinnerLabel              matlab.ui.control.Label
        YSpinner                   matlab.ui.control.Spinner
        ZSpinnerLabel              matlab.ui.control.Label
        ZSpinner                   matlab.ui.control.Spinner
        XSpinnerLabel              matlab.ui.control.Label
        XSpinner                   matlab.ui.control.Spinner
        UpButton                   matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        DownButton                 matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        KnobLabel                  matlab.ui.control.Label
        Knob                       matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = public)
        robot % Description
    end
    
    methods (Access = public)
        % pass in robot object
        function addRobot(app, robot)
            app.robot = robot;
            app.updateAll;
            
            app.PauseButton.Value = 1;
            app.OpenGripperButton.Value = 1;
          
        end
        
        function updateBox(app)
            q = rad2deg(app.robot.model.getpos);
            boxlims = [app.Joint1Box.Limits; app.Joint2Box.Limits; app.Joint3Box.Limits; app.Joint4Box.Limits; app.Joint5Box.Limits; app.Joint6Box.Limits; app.Joint7Box.Limits];
            boxval = [app.Joint1Box.Value app.Joint2Box.Value app.Joint3Box.Value app.Joint4Box.Value app.Joint5Box.Value app.Joint6Box.Value app.Joint7Box.Value];
            
            for i=1:length(q)
                if q(i) < boxlims(i,1)
                    boxval(i) = boxlims(i,1);
                elseif q(i) > boxlims(i,2)
                    boxval(i) = boxlims(i,2);
                else
                    boxval(i) = q(i);
                end
            end
            
            app.Joint1Box.Value = boxval(1);
            app.Joint2Box.Value = boxval(2);
            app.Joint3Box.Value = boxval(3);
            app.Joint4Box.Value = boxval(4);
            app.Joint5Box.Value = boxval(5);
            app.Joint6Box.Value = boxval(6);
            app.Joint7Box.Value = boxval(7);
        end
        
        function updateSliders(app)
            app.Joint1Slider.Value = app.Joint1Box.Value;
            app.Joint2Slider.Value = app.Joint2Box.Value;
            app.Joint3Slider.Value = app.Joint3Box.Value;
            app.Joint4Slider.Value = app.Joint4Box.Value;
            app.Joint5Slider.Value = app.Joint5Box.Value;
            app.Joint6Slider.Value = app.Joint6Box.Value;
            app.Joint7Slider.Value = app.Joint7Box.Value;
        end
        
        function updateSpinner(app)
            q = app.robot.model.getpos;
            T = app.robot.model.fkine(q);
            app.XSpinner.Value = T(1,4);
            app.YSpinner.Value = T(2,4);
            app.ZSpinner.Value = T(3,4);
        end
        
        function updateAll(app)
            app.updateBox;
            app.updateSliders;
            app.updateSpinner;
        end
        
        function setEstop(app)
            app.EmergencyStopButtonValueChanged;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Box
        function Joint1BoxValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint1Slider.Value = app.Joint1Box.Value;
                q = app.robot.model.getpos;
                q(1) = deg2rad(app.Joint1Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateSliders;
            end
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint1Box.Value = app.Joint1Slider.Value;
                q = app.robot.model.getpos;
                q(1) = deg2rad(app.Joint1Box.Value);
            
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateBox;
            end
            
        end

        % Value changed function: Joint2Box
        function Joint2BoxValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint2Slider.Value = app.Joint2Box.Value;
                q = app.robot.model.getpos;
                q(2) = deg2rad(app.Joint2Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateSliders;
            end
        end

        % Value changed function: Joint3Box
        function Joint3BoxValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint3Slider.Value = app.Joint3Box.Value;
                q = app.robot.model.getpos;
                q(3) = deg2rad(app.Joint3Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateSliders;
            end
        end

        % Value changed function: Joint4Box
        function Joint4BoxValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint4Slider.Value = app.Joint4Box.Value;
                q = app.robot.model.getpos;
                q(4) = deg2rad(app.Joint4Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateSliders;
            end
        end

        % Value changed function: Joint5Box
        function Joint5BoxValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint5Slider.Value = app.Joint5Box.Value;
                q = app.robot.model.getpos;
                q(5) = deg2rad(app.Joint5Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateSliders;
            end
        end

        % Value changed function: Joint6Box
        function Joint6BoxValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint6Slider.Value = app.Joint6Box.Value;
                q = app.robot.model.getpos;
                q(6) = deg2rad(app.Joint6Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateSliders;
            end
        end

        % Value changed function: Joint7Box
        function Joint7BoxValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint7Slider.Value = app.Joint7Box.Value;
                q = app.robot.model.getpos;
                q(7) = deg2rad(app.Joint7Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateSliders;
            end
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint2Box.Value = app.Joint2Slider.Value;
                q = app.robot.model.getpos;
                q(2) = deg2rad(app.Joint2Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateBox;
            end
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint3Box.Value = app.Joint3Slider.Value;
                q = app.robot.model.getpos;
                q(3) = deg2rad(app.Joint3Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateBox;
            end
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint4Box.Value = app.Joint4Slider.Value;
                q = app.robot.model.getpos;
                q(4) = deg2rad(app.Joint4Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateBox;
            end
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint5Box.Value = app.Joint5Slider.Value;
                q = app.robot.model.getpos;
                q(5) = deg2rad(app.Joint5Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateBox;
            end
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint6Box.Value = app.Joint6Slider.Value;
                q = app.robot.model.getpos;
                q(6) = deg2rad(app.Joint6Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateBox;
            end
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            if app.StartButton.Value == 1
                app.Joint7Box.Value = app.Joint7Slider.Value;
                q = app.robot.model.getpos;
                q(7) = deg2rad(app.Joint7Box.Value);
                app.robot.Move2JointState(q, 100);
                app.updateSpinner;
                app.updateBox;
            end
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
%           Default position
            q = [0.7854   -0.6767         0   -0.0001   -6.2832    2.1594    3.1409];
            
            app.Joint1Box.Value = q(1);
            app.Joint2Box.Value = q(2);
            app.Joint3Box.Value = q(3);
            app.Joint4Box.Value = q(4);
            app.Joint5Box.Value = q(5);
            app.Joint6Box.Value = q(6);
            app.Joint7Box.Value = q(7);
            
            app.Joint1Slider.Value = q(1);
            app.Joint2Slider.Value = q(2);
            app.Joint3Slider.Value = q(3);
            app.Joint4Slider.Value = q(4);
            app.Joint5Slider.Value = q(5);
            app.Joint6Slider.Value = q(6);
            app.Joint7Slider.Value = q(7);
            
            app.robot.Move2JointState(q, 100);
            app.updateAll;
            app.ResetButton.Value = 0;
        end

        % Callback function
        function SetButton_2ValueChanged(app, event)

            
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            if app.StartButton.Value == 1
                pose = [app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value];
                app.robot.Move2Pose(pose, 50);
                app.updateBox;
                app.updateSliders;
            end
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            if app.StartButton.Value == 1
                pose = [app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value];
                app.robot.Move2Pose(pose, 50);
                app.updateBox;
                app.updateSliders;
            end
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            if app.StartButton.Value == 1
                pose = [app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value];
                app.robot.Move2Pose(pose, 50);
                app.updateBox;
                app.updateSliders;
            end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if app.EmergencyStopButton.Value == 0
                if value == 1
                    app.PauseButton.Value = 0;
                    app.updateAll
                    
                    if app.robot.active_traj == 1
                        if length(app.robot.q_before_pause) == 7
                            app.robot.Move2JointState(app.robot.q_before_pause,50);
                        end
                    end
                else
                    app.StartButton.Value = 1;
                end
            else
                app.StartButton.Value = 0;
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if app.EmergencyStopButton.Value == 0
                if value == 1
                    app.StartButton.Value = 0;
                else
                    app.PauseButton.Value = 1;
                end
            else
                app.PauseButton.Value = 1;
            end
        end

        % Value changed function: EmergencyStopButton
        function EmergencyStopButtonValueChanged(app, event)
            value = app.EmergencyStopButton.Value;
            if value == 1
                app.EmergencyStopButton.BackgroundColor = [1,0,0];
                app.StartButton.Value = 0;
                app.PauseButton.Value = 1;
            else
                app.EmergencyStopButton.BackgroundColor = [1,1,1];
            end
        end

        % Value changed function: OpenGripperButton
        function OpenGripperButtonValueChanged(app, event)
            value = app.OpenGripperButton.Value;
            if value == 1
                app.robot.OpenGripper(value)
                app.CloseGripperButton.Value = 0;
            else
                app.OpenGripperButton.Value = 1;
            end
        end

        % Value changed function: CloseGripperButton
        function CloseGripperButtonValueChanged(app, event)
            value = app.CloseGripperButton.Value;
            if value == 1
                app.robot.OpenGripper(0)
                app.OpenGripperButton.Value = 0;
            else
                app.CloseGripperButton.Value = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.OpenGripperButton = uibutton(app.UIFigure, 'state');
            app.OpenGripperButton.ValueChangedFcn = createCallbackFcn(app, @OpenGripperButtonValueChanged, true);
            app.OpenGripperButton.Text = 'Open Gripper';
            app.CloseGripperButton = uibutton(app.UIFigure, 'state');
            app.CloseGripperButton.ValueChangedFcn = createCallbackFcn(app, @CloseGripperButtonValueChanged, true);
            app.CloseGripperButton.Text = 'Close Gripper';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'state');
            app.EmergencyStopButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopButtonValueChanged, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.JointSlidersPanel = uipanel(app.UIFigure);
            app.JointSlidersPanel.Title = 'Joint Sliders';
            app.Joint1SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.JointSlidersPanel);
            app.Joint1Slider.Limits = [-92 92];
            app.Joint1Slider.MajorTicks = [-92 0 92];
            app.Joint1Slider.MajorTickLabels = {'-92', '0', '92'};
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint1Slider.Value = 1.32;
            app.Joint2SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.JointSlidersPanel);
            app.Joint2Slider.Limits = [-70 87];
            app.Joint2Slider.MajorTicks = [-70 0 87];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint2Slider.Value = 1.4;
            app.Joint3SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.JointSlidersPanel);
            app.Joint3Slider.Limits = [-360 360];
            app.Joint3Slider.MajorTicks = [-360 0 360];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.MinorTicks = [];
            app.Joint3Slider.Value = 1.32;
            app.Joint4SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.JointSlidersPanel);
            app.Joint4Slider.Limits = [-129 129];
            app.Joint4Slider.MajorTicks = [-129 0 129];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint4Slider.Value = 1.32;
            app.Joint5SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5Slider = uislider(app.JointSlidersPanel);
            app.Joint5Slider.Limits = [-360 360];
            app.Joint5Slider.MajorTicks = [-360 0 360];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.MinorTicks = [];
            app.Joint5Slider.Value = 1.32;
            app.Joint6SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6Slider = uislider(app.JointSlidersPanel);
            app.Joint6Slider.Limits = [-125 125];
            app.Joint6Slider.MajorTicks = [-125 0 125];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.MinorTicks = [];
            app.Joint6Slider.Value = 1.32;
            app.Joint7SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint7SliderLabel.Text = 'Joint7';
            app.Joint7Slider = uislider(app.JointSlidersPanel);
            app.Joint7Slider.Limits = [-360 360];
            app.Joint7Slider.MajorTicks = [-360 0 360];
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.Joint7Slider.MinorTicks = [];
            app.Joint7Slider.Value = 1.32;
            app.JointValuesPanel = uipanel(app.UIFigure);
            app.JointValuesPanel.Title = 'Joint Values';
            app.Joint1Box = uieditfield(app.JointValuesPanel, 'numeric');
            app.Joint1Box.Limits = [-92 92];
            app.Joint1Box.ValueDisplayFormat = '%.3f';
            app.Joint1Box.ValueChangedFcn = createCallbackFcn(app, @Joint1BoxValueChanged, true);
            app.Joint1Box.Value = 1;
            app.Joint2Box = uieditfield(app.JointValuesPanel, 'numeric');
            app.Joint2Box.Limits = [-70 87];
            app.Joint2Box.ValueDisplayFormat = '%.3f';
            app.Joint2Box.ValueChangedFcn = createCallbackFcn(app, @Joint2BoxValueChanged, true);
            app.Joint2Box.Value = 1;
            app.Joint3Box = uieditfield(app.JointValuesPanel, 'numeric');
            app.Joint3Box.Limits = [-360 360];
            app.Joint3Box.ValueDisplayFormat = '%.3f';
            app.Joint3Box.ValueChangedFcn = createCallbackFcn(app, @Joint3BoxValueChanged, true);
            app.Joint4Box = uieditfield(app.JointValuesPanel, 'numeric');
            app.Joint4Box.Limits = [-129 129];
            app.Joint4Box.ValueDisplayFormat = '%.3f';
            app.Joint4Box.ValueChangedFcn = createCallbackFcn(app, @Joint4BoxValueChanged, true);
            app.Joint4Box.Value = 2;
            app.Joint5Box = uieditfield(app.JointValuesPanel, 'numeric');
            app.Joint5Box.Limits = [-360 360];
            app.Joint5Box.ValueDisplayFormat = '%.3f';
            app.Joint5Box.ValueChangedFcn = createCallbackFcn(app, @Joint5BoxValueChanged, true);
            app.Joint6Box = uieditfield(app.JointValuesPanel, 'numeric');
            app.Joint6Box.Limits = [-125 125];
            app.Joint6Box.ValueDisplayFormat = '%.3f';
            app.Joint6Box.ValueChangedFcn = createCallbackFcn(app, @Joint6BoxValueChanged, true);
            app.Joint6Box.Value = 2;
            app.Joint7Box = uieditfield(app.JointValuesPanel, 'numeric');
            app.Joint7Box.Limits = [-360 360];
            app.Joint7Box.ValueDisplayFormat = '%.3f';
            app.Joint7Box.ValueChangedFcn = createCallbackFcn(app, @Joint7BoxValueChanged, true);
            app.EndEffectorpositionsPanel = uipanel(app.UIFigure);
            app.EndEffectorpositionsPanel.Title = 'End Effector positions';
            app.YSpinnerLabel = uilabel(app.EndEffectorpositionsPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.EndEffectorpositionsPanel);
            app.YSpinner.Step = 0.01;
            app.YSpinner.Limits = [-0.7 0.98];
            app.YSpinner.ValueDisplayFormat = '%.3f';
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.EndEffectorpositionsPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.EndEffectorpositionsPanel);
            app.ZSpinner.Step = 0.01;
            app.ZSpinner.Limits = [0 2];
            app.ZSpinner.ValueDisplayFormat = '%.3f';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.XSpinnerLabel = uilabel(app.EndEffectorpositionsPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.EndEffectorpositionsPanel);
            app.XSpinner.Step = 0.01;
            app.XSpinner.Limits = [-0.94 0.94];
            app.XSpinner.ValueDisplayFormat = '%.3f';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.UpButton = uibutton(app.EndEffectorpositionsPanel, 'push');
            app.UpButton.Text = 'Up';
            app.LeftButton = uibutton(app.EndEffectorpositionsPanel, 'push');
            app.LeftButton.Text = 'Left';
            app.DownButton = uibutton(app.EndEffectorpositionsPanel, 'push');
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.EndEffectorpositionsPanel, 'push');
            app.RightButton.Text = 'Right';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Knob.Value = '50';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end